classdef sigPreProcessAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        PreprocessingFunctionSettingsPanel  matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        DefaultsButton                  matlab.ui.control.Button
        FunctionDetailLabel             matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        CurrentFolderEditField          matlab.ui.control.EditField
        CurrentFolderEditFieldLabel     matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel  matlab.ui.control.Label
        EEGPreprocessingAnalyzerv1Label  matlab.ui.control.Label
        FeatureGenerationPanel          matlab.ui.container.Panel
        PostProcessingPanel             matlab.ui.container.Panel
        PostProcessPlot                 matlab.ui.control.UIAxes
        PreprocessingPanel              matlab.ui.container.Panel
        EditFunctionActivateButtonGroup  matlab.ui.container.ButtonGroup
        UpdateOrderButton_2             matlab.ui.control.Button
        RunPipelineButton               matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        Switch                          matlab.ui.control.Switch
        HighPassFilterButton            matlab.ui.control.RadioButton
        ResampleButton                  matlab.ui.control.RadioButton
        FilesPanel                      matlab.ui.container.Panel
        LoadSelectedSETFileButton       matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        EEGStructureofSelectedSETLabel  matlab.ui.control.Label
        AvailableSETfilesinCurrentPathListBox  matlab.ui.control.ListBox
        AvailableSETfilesinCurrentPathListBoxLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        spac % reserved for controller class
        pipeButton              matlab.ui.control.RadioButton;
        pipeSwitch              matlab.ui.control.Switch;
        pipeOrder               matlab.ui.control.NumericEditField;
        pipeInputLabel      matlab.ui.control.Label;
        pipeInputEditField      matlab.ui.control.EditField;
    end

    methods (Access = private)

        function updateCurrentDirectory(app)
            app.CurrentFolderEditField.Value = app.spac.current_folder;

            % list files in file table from current directory
            app.spac.openFolder;
            app.AvailableSETfilesinCurrentPathListBox.Items = app.spac.current_files;

        end

        function results = setupPipelineLayout(app)

            ppTbl =  app.spac.panel_config;

            ppTbl = sortrows(ppTbl, {'ORDER'});

            query =  ppTbl.PANEL == "PREPROCESS" & ppTbl.SUBPANEL == "PIPELINE";

            query_results = ppTbl(query, :);

            yinit = 0;
            yoffset = 26; % px

            % preallocation

            for i = 1 : height(query_results)

                app.pipeButton(i) =  uiradiobutton(app.EditFunctionActivateButtonGroup);
                app.pipeSwitch(i) = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
                app.pipeOrder(i) = uieditfield(app.EditFunctionActivateButtonGroup, 'numeric');

            end

            paneHeight = app.EditFunctionActivateButtonGroup.Position(4);
            parameterHeight = 26 *  height(query_results) ;
            topMargin = 20;
            finalYoffset = paneHeight - parameterHeight - topMargin;
            ystart = yinit + finalYoffset;

            for i = height(query_results):-1:1

                uilabel =  query_results.LABEL{i,1};
                % uiorder =  query_results.ORDER(i);

                ppTbl.ORDER(i) = i;

                % Reprogram Preallocated switches
                app.pipeButton(i).Text = uilabel;
                app.pipeButton(i).Position = [30 ystart 150 22];
                app.pipeButton(i).Value = false;

                app.pipeSwitch(i).Items   = {'Off','On'};
                app.pipeSwitch(i).Position = [190 ystart+4 35 22];

                app.pipeOrder(i).Position =  [2 ystart 25 22];
                app.pipeOrder(i).Value = i;

                ystart = ystart + yoffset;
            end

        end


        function results = setupDetailLayout(app)
            detailTbl = app.spac.current_detailTable;
            if ~isempty(detailTbl)
            sourcename = char(unique(detailTbl.SOURCE));
            switch sourcename
                case 'vhtp'
                    href = 'https://github.com/cincibrainlab/vhtp/blob/main/';
                case 'eeglab'
                    href = 'https://github.com/sccn/eeglab/blob/develop/functions/popfunc/';
            end

            fxname = unique(detailTbl.FUNCTION);
            
            app.StatusTextArea.FontColor = 'k';
            app.StatusTextArea.Value = sprintf("Input Details for %s.", fxname{1});

            ghlink = sprintf("<a href=%s%s.m>%s</a>", ...
                href, fxname{1}, fxname{1});
            app.FunctionDetailLabel.Text = ghlink;
            app.FunctionDetailLabel.Interpreter = 'html';
            app.FunctionDetailLabel.Visible = 'on';

            yinit = 0;
            topMargin = 40;
            yoffset = 25; % px
            paneHeight = app.PreprocessingFunctionSettingsPanel.Position(4);
            parameterHeight = 25 *  height(detailTbl) ;
            finalYoffset = paneHeight - parameterHeight - topMargin;
            ystart = yinit + finalYoffset;

            % Preallocate Inputs
            for i = 1 : height(detailTbl)
                app.pipeInputLabel(i)     =  uilabel(app.PreprocessingFunctionSettingsPanel);
                app.pipeInputEditField(i) =  uieditfield(app.PreprocessingFunctionSettingsPanel);
            end

            for i = height(detailTbl):-1:1
                current_row = detailTbl(i,:);
                app.pipeInputLabel(i).Text = detailTbl{i,{'INPUT'}};
                app.pipeInputLabel(i).Position = [12 ystart 29 22];
                app.pipeInputEditField(i).Position = [101 ystart 152 22];
                ystart = ystart + yoffset;
            end

            else
               app.StatusTextArea.FontColor = 'r';
               app.StatusTextArea.Value = sprintf("No function defined in spaControllerPanelDetail.csv"); 
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.spac = spaController();
            app.StatusTextArea.Value = sprintf("%s", app.StatusTextArea.Value{1}, "Created Controller Class");

            if app.spac.saveSettingsExists
                app.spac.loadSettings
                app.updateCurrentDirectory
            end

            app.RunPipelineButton.Enable = 'off';
            app.setupPipelineLayout;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %create a dummy figure so that uigetdir doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            app.spac.selectFolder;
            app.updateCurrentDirectory;
            delete(f); %delete the dummy figure

            % save settings
            app.spac.saveSettings;

        end

        % Value changed function: 
        % AvailableSETfilesinCurrentPathListBox
        function AvailableSETfilesinCurrentPathListBoxValueChanged(app, event)
            value = app.AvailableSETfilesinCurrentPathListBox.Value;
            
            % allow ability to run pipeline if file is selected
            app.RunPipelineButton.Enable = 'on';

            % populate details
            app.spac.showSetStructure( value );
            app.TextArea.Value = app.spac.current_details;
            
            % update user selected file
            app.spac.selected_file = value;
            
        end

        % Button pushed function: UpdateOrderButton_2
        function UpdateOrderButton_2Pushed(app, event)

            % get current order
            app.spac.storeNewPipeOrder([app.pipeOrder.Value]);

            % check for duplicates

            % modify datatable
            app.spac.updateConfigCsv('updateOrder');

            % rerun button generation
            app.setupPipelineLayout;

        end

        % Selection changed function: 
        % EditFunctionActivateButtonGroup
        function EditFunctionActivateButtonGroupSelectionChanged(app, event)
            % get user input
            selectedButton = app.EditFunctionActivateButtonGroup.SelectedObject;
            functionColumn = categorical( app.spac.panel_config.LABEL );

            % identify correct row
            fxnIdx = find(functionColumn == selectedButton.Text);
            queryrow = app.spac.panel_config(fxnIdx,:);

            % store detail information regarding selected function
            app.spac.getDetailTableByFunName( queryrow.FUNCTION{1} );

            app.setupDetailLayout()

        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            
            % check which pipeline items are on
            pipelineOn = categorical({app.pipeSwitch.Value}) == "On";
            
            % load EEG
            if any(pipelineOn)
                
            else
                % load and plot EEG timeseries
                app.spac.loadEEG;
            end
            
            % View time series
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.AvailableSETfilesinCurrentPathListBoxLabel = uilabel(app.FilesPanel);
            app.AvailableSETfilesinCurrentPathListBoxLabel.Text = 'Available SET files in Current Path';
            app.AvailableSETfilesinCurrentPathListBox = uilistbox(app.FilesPanel);
            app.AvailableSETfilesinCurrentPathListBox.Items = {};
            app.AvailableSETfilesinCurrentPathListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableSETfilesinCurrentPathListBoxValueChanged, true);
            app.AvailableSETfilesinCurrentPathListBox.Value = {};
            app.EEGStructureofSelectedSETLabel = uilabel(app.FilesPanel);
            app.EEGStructureofSelectedSETLabel.Text = 'EEG Structure of Selected SET';
            app.TextArea = uitextarea(app.FilesPanel);
            app.LoadSelectedSETFileButton = uibutton(app.FilesPanel, 'push');
            app.LoadSelectedSETFileButton.Text = 'Load Selected SET File';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.EditFunctionActivateButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.EditFunctionActivateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFunctionActivateButtonGroupSelectionChanged, true);
            app.EditFunctionActivateButtonGroup.Title = '  Edit Function                                Activate';
            app.ResampleButton = uiradiobutton(app.EditFunctionActivateButtonGroup);
            app.ResampleButton.Visible = 'off';
            app.ResampleButton.Text = 'Resample';
            app.ResampleButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.EditFunctionActivateButtonGroup);
            app.HighPassFilterButton.Visible = 'off';
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.Switch = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
            app.Switch.Visible = 'off';
            app.Switch_2 = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
            app.Switch_2.Visible = 'off';
            app.RunPipelineButton = uibutton(app.EditFunctionActivateButtonGroup, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.UpdateOrderButton_2 = uibutton(app.EditFunctionActivateButtonGroup, 'push');
            app.UpdateOrderButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateOrderButton_2Pushed, true);
            app.UpdateOrderButton_2.Text = 'Update Order';
            app.PostProcessingPanel = uipanel(app.UIFigure);
            app.PostProcessingPanel.Title = 'PostProcessing';
            app.PostProcessPlot = uiaxes(app.PostProcessingPanel);
            title(app.PostProcessPlot, 'Title')
            xlabel(app.PostProcessPlot, 'X')
            ylabel(app.PostProcessPlot, 'Y')
            zlabel(app.PostProcessPlot, 'Z')
            app.FeatureGenerationPanel = uipanel(app.UIFigure);
            app.FeatureGenerationPanel.Title = 'Feature Generation';
            app.EEGPreprocessingAnalyzerv1Label = uilabel(app.UIFigure);
            app.EEGPreprocessingAnalyzerv1Label.Text = 'EEG Preprocessing Analyzer v.1';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel = uilabel(app.UIFigure);
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Interpreter = 'html';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Text = '<a href=https://github.com/cincibrainlab/sigPreProcessAnalyzer>https://github.com/cincibrainlab/sigPreProcessAnalyzer</a>';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.CurrentFolderEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder:';
            app.CurrentFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.PreprocessingFunctionSettingsPanel = uipanel(app.UIFigure);
            app.PreprocessingFunctionSettingsPanel.Title = 'Preprocessing Function Settings';
            app.FunctionDetailLabel = uilabel(app.PreprocessingFunctionSettingsPanel);
            app.FunctionDetailLabel.Visible = 'off';
            app.FunctionDetailLabel.Text = 'FunctionDetailLabel';
            app.DefaultsButton = uibutton(app.PreprocessingFunctionSettingsPanel, 'push');
            app.DefaultsButton.Text = 'Defaults';
            app.SaveButton = uibutton(app.PreprocessingFunctionSettingsPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigPreProcessAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end